classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImagePickerUIFigure  matlab.ui.Figure
        PickanImageLabel     matlab.ui.control.Label
        ShotandeditedbyHaythemGataacLabel  matlab.ui.control.Label
        Button               matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        Button_3             matlab.ui.control.Button
        Button_4             matlab.ui.control.Button
        Button_5             matlab.ui.control.Button
        Button_6             matlab.ui.control.Button
        Button_7             matlab.ui.control.Button
        Button_8             matlab.ui.control.Button
        homeButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        OpenImage 
        Home
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            im = imread('mp1.jpg');
            setappdata(0,'x',im);
            setappdata(0,'name','mp1.jpg');
            app.OpenImage = app3;
            delete(app)        
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            im = imread('mp2.jpg');
            setappdata(0,'x',im);
            setappdata(0,'name','mp2.jpg');
            app.OpenImage = app3;
            delete(app)    
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            im = imread('mp3.jpg');
            setappdata(0,'x',im);
            setappdata(0,'name','mp3.jpg');
            app.OpenImage = app3;
            delete(app)  
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            im = imread('mp4.jpg');
            setappdata(0,'x',im);
            setappdata(0,'name','mp4.jpg');
            app.OpenImage = app3;
            delete(app)  
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            im = imread('mp5.jpg');
            setappdata(0,'x',im);
            setappdata(0,'name','mp5.jpg');
            app.OpenImage = app3;
            delete(app)
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            im = imread('mp6.jpg');
            setappdata(0,'x',im);
            setappdata(0,'name','mp6.jpg');
            app.OpenImage = app3;
            delete(app)  
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            im = imread('mp7.jpg');
            setappdata(0,'x',im);
            setappdata(0,'name','mp7.jpg');
            app.OpenImage = app3;
            delete(app)  
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            im = imread('mp8.jpg');
            setappdata(0,'x',im);
            setappdata(0,'name','mp8.jpg');
            app.OpenImage = app3;
            delete(app)  
        end

        % Button pushed function: homeButton
        function homeButtonPushed(app, event)
            app.Home = MP;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImagePickerUIFigure = uifigure('Visible', 'off');
            app.ImagePickerUIFigure.Name = 'Image Picker';
            app.PickanImageLabel = uilabel(app.ImagePickerUIFigure);
            app.PickanImageLabel.Text = 'Pick an Image :';
            app.ShotandeditedbyHaythemGataacLabel = uilabel(app.ImagePickerUIFigure);
            app.ShotandeditedbyHaythemGataacLabel.Text = 'Shot and edited by Haythem Gataa (c)';
            app.Button = uibutton(app.ImagePickerUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'mp1.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.ImagePickerUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'mp2.jpg';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.ImagePickerUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'mp3.jpg';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.ImagePickerUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'mp4.jpg';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.ImagePickerUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'mp5.jpg';
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.ImagePickerUIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = 'mp6.jpg';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.ImagePickerUIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = 'mp7.jpg';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.ImagePickerUIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Icon = 'mp8.jpg';
            app.Button_8.Text = '';
            app.homeButton = uibutton(app.ImagePickerUIFigure, 'push');
            app.homeButton.ButtonPushedFcn = createCallbackFcn(app, @homeButtonPushed, true);
            app.homeButton.Icon = 'round_home_white_18dp.png';
            app.homeButton.Text = '';
            % Show the figure after all components are created
            app.ImagePickerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImagePickerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImagePickerUIFigure)
        end
    end
end