classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageUIFigure          matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        Panel                  matlab.ui.container.Panel
        BrightnessSliderLabel  matlab.ui.control.Label
        BrightnessSlider       matlab.ui.control.Slider
        ContrastSliderLabel    matlab.ui.control.Label
        ContrastSlider         matlab.ui.control.Slider
        BrLamp                 matlab.ui.control.Lamp
        CntLamp                matlab.ui.control.Lamp
        RLamp                  matlab.ui.control.Lamp
        RedSliderLabel         matlab.ui.control.Label
        RedSlider              matlab.ui.control.Slider
        GLamp                  matlab.ui.control.Lamp
        GreenSliderLabel       matlab.ui.control.Label
        GreenSlider            matlab.ui.control.Slider
        BLamp                  matlab.ui.control.Lamp
        BlueSliderLabel        matlab.ui.control.Label
        BlueSlider             matlab.ui.control.Slider
        RedButton              matlab.ui.control.Button
        GreenButton            matlab.ui.control.Button
        BlueButton             matlab.ui.control.Button
        GrayscaleButton        matlab.ui.control.Button
        BinaryButton           matlab.ui.control.Button
        RoLamp                 matlab.ui.control.Lamp
        RotateSliderLabel      matlab.ui.control.Label
        RotateSlider           matlab.ui.control.Slider
        Label                  matlab.ui.control.Label
        Switch                 matlab.ui.control.Switch
        ResetButton            matlab.ui.control.Button
        iButton                matlab.ui.control.Button
        homeButton             matlab.ui.control.Button
        histButton             matlab.ui.control.Button
    end

    properties (Access= public)
        Home
        im
        edited
        editedLocal
        histo
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plot(app.UIAxes,[1 2 3 4],'-r');
            hold(app.UIAxes);
            app.im = getappdata(0,'x');
            app.edited = app.im;
            app.Switch.Value = 'Original';
            imshow(app.edited,'Parent',app.UIAxes);
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            app.Switch.Value = 'Edited';
            app.BrLamp.Color = 'green';
            app.BrightnessSlider.Value = 0;
            app.edited = app.editedLocal;
            if app.histo == 1
                imhist(app.edited)
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            test = app.edited;
            
            switch app.Switch.Value
                case 'Original'
                    imshow(app.im, 'Parent', app.UIAxes);
                case 'Edited'
                    imshow(test, 'Parent', app.UIAxes);
            end                                 
        end

        % Callback function
        function ButtonValueChanged(app, event)
      
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.edited = app.im;
            app.Switch.Value = 'Original';
            app.histo = 0;
            app.BlueButton.BackgroundColor = [0.651 0.651 0.651];
            app.BlueButton.FontColor = [0 0.4471 0.7412];
            app.RedButton.BackgroundColor = [0.651 0.651 0.651];
            app.RedButton.FontColor = [1 0.1412 0.1412];
            app.GreenButton.BackgroundColor = [0.651 0.651 0.651];
            app.GreenButton.FontColor = [0.1765 0.6588 0.0118];
            app.BinaryButton.BackgroundColor = [0.651 0.651 0.651];
            app.BinaryButton.FontColor = [1 1 1];
            app.GrayscaleButton.BackgroundColor = [0.651 0.651 0.651];
            app.GrayscaleButton.FontColor = [0.9412 0.9412 0.9412];
            app.BrLamp.Color = [0.502 0.502 0.502];
            app.CntLamp.Color = [0.502 0.502 0.502];
            app.RLamp.Color = [0.502 0.502 0.502];
            app.GLamp.Color = [0.502 0.502 0.502];
            app.BLamp.Color = [0.502 0.502 0.502];
            app.RoLamp.Color = [0.502 0.502 0.502];
            imshow(app.edited,'Parent', app.UIAxes);
        end

        % Button pushed function: iButton
        function iButtonPushed(app, event)
                         fname = getappdata(0,'name');
            if strcmpi(fname,'mp1.jpg')
                imageinfo('mp1.jpg')
            elseif strcmpi(fname,'mp2.jpg')
                imageinfo('mp2.jpg')
            elseif strcmpi(fname,'mp3.jpg')
                imageinfo('mp3.jpg')
            elseif strcmpi(fname,'mp4.jpg')
                imageinfo('mp4.jpg')
            elseif strcmpi(fname,'mp5.jpg')
                imageinfo('mp5.jpg')
            elseif strcmpi(fname,'mp6.jpg')
                imageinfo('mp6.jpg')
            elseif strcmpi(fname,'mp7.jpg')
                imageinfo('mp7.jpg')
            else
                imageinfo('mp8.jpg')
            end
            
        end

        % Button pushed function: homeButton
        function homeButtonPushed(app, event)
            app.Home = MP;
            delete(app)
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            app.Switch.Value = 'Edited';
            app.CntLamp.Color = 'green';
            app.ContrastSlider.Value = 0;
            app.edited = app.editedLocal;
            if app.histo == 1
                imhist(app.edited)
            end
        end

        % Value changing function: BrightnessSlider
        function BrightnessSliderValueChanging(app, event)
            changingValue = event.Value;
            app.editedLocal = app.edited+changingValue;
            imshow(app.editedLocal, 'Parent', app.UIAxes);
            app.ContrastSlider.Value = 0;
            
            
        end

        % Value changing function: ContrastSlider
        function ContrastSliderValueChanging(app, event)
            changingValue = event.Value;
            if changingValue>0
                app.editedLocal = imadjust(app.edited,[],[changingValue,1]);
            else
                app.editedLocal = imadjust(app.edited,[-changingValue,1],[]);
            end
            imshow(app.editedLocal,'Parent', app.UIAxes);
           
        end

        % Button pushed function: histButton
        function histButtonPushed(app, event)
            app.histo = 1;
            imhist (app.edited)
        end

        % Value changing function: RedSlider
        function RedSliderValueChanging(app, event)
            changingValue = event.Value;    
            rgbImage = app.edited;
            redChannel = rgbImage(:, :, 1);
            greenChannel = rgbImage(:, :, 2);
            blueChannel = rgbImage(:, :, 3);
            slopeR = changingValue;
            interceptR = changingValue;
            redChannel = slopeR * redChannel + interceptR;
            app.editedLocal = cat(3, redChannel, greenChannel, blueChannel);
            imshow(app.editedLocal,'Parent', app.UIAxes);
        end

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            app.edited = app.editedLocal;
            app.Switch.Value = 'Edited';
            app.RLamp.Color = 'green';
            app.RedSlider.Value = 2.5;
            if app.histo == 1
                imhist(app.edited)
            end
        end

        % Button pushed function: RedButton
        function RedButtonPushed(app, event)
            redChannel = app.edited;
            redChannel(:,:,[2 3])=0;
            imshow(redChannel,'Parent', app.UIAxes);
            app.RedButton.BackgroundColor = [1 0.1412 0.1412];
            app.RedButton.FontColor = [1 1 1];
            app.GreenButton.BackgroundColor = [0.651 0.651 0.651];
            app.GreenButton.FontColor = [0.1765 0.6588 0.0118];
            app.BlueButton.BackgroundColor = [0.651 0.651 0.651];
            app.BlueButton.FontColor = [0 0.4471 0.7412];
            app.BinaryButton.BackgroundColor = [0.651 0.651 0.651];
            app.BinaryButton.FontColor = [1 1 1];
            app.GrayscaleButton.BackgroundColor = [0.651 0.651 0.651];
            app.GrayscaleButton.FontColor = [0.9412 0.9412 0.9412];
            if app.histo == 1
                imhist(redChannel)
            end
        end

        % Button pushed function: GreenButton
        function GreenButtonPushed(app, event)
            greenChannel = app.edited;
            greenChannel(:,:,[1 3])=0;
            imshow(greenChannel,'Parent', app.UIAxes);
            app.GreenButton.BackgroundColor = [0.0471 0.7882 0.0471];
            app.GreenButton.FontColor = [1 1 1];
            app.BlueButton.BackgroundColor = [0.651 0.651 0.651];
            app.BlueButton.FontColor = [0 0.4471 0.7412];
            app.RedButton.BackgroundColor = [0.651 0.651 0.651];
            app.RedButton.FontColor = [1 0.1412 0.1412];
            app.BinaryButton.BackgroundColor = [0.651 0.651 0.651];
            app.BinaryButton.FontColor = [1 1 1];
            app.GrayscaleButton.BackgroundColor = [0.651 0.651 0.651];
            app.GrayscaleButton.FontColor = [0.9412 0.9412 0.9412];
            if app.histo == 1
                imhist(greenChannel)
            end
        end

        % Button pushed function: BlueButton
        function BlueButtonPushed(app, event)
            blueChannel = app.edited;
            blueChannel(:,:,[2 1])=0;
            imshow(blueChannel,'Parent', app.UIAxes);
            app.BlueButton.BackgroundColor = [0 0.4471 0.7412];
            app.BlueButton.FontColor = [1 1 1];
            app.RedButton.BackgroundColor = [0.651 0.651 0.651];
            app.RedButton.FontColor = [1 0.1412 0.1412];
            app.GreenButton.BackgroundColor = [0.651 0.651 0.651];
            app.GreenButton.FontColor = [0.1765 0.6588 0.0118];
            app.BinaryButton.BackgroundColor = [0.651 0.651 0.651];
            app.BinaryButton.FontColor = [1 1 1];
            app.GrayscaleButton.BackgroundColor = [0.651 0.651 0.651];
            app.GrayscaleButton.FontColor = [0.9412 0.9412 0.9412];
            if app.histo == 1
                imhist(blueChannel)
            end
        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            app.edited = app.editedLocal;
            app.Switch.Value = 'Edited';
            app.GLamp.Color = 'green';
            app.GreenSlider.Value = 2.5;
            if app.histo == 1
                imhist(app.edited)
            end
            
        end

        % Value changing function: GreenSlider
        function GreenSliderValueChanging(app, event)
            changingValue = event.Value;    
            rgbImage = app.edited;
            redChannel = rgbImage(:, :, 1);
            greenChannel = rgbImage(:, :, 2);
            blueChannel = rgbImage(:, :, 3);
            slopeR = changingValue;
            interceptR = changingValue;
            greenChannel = slopeR * greenChannel + interceptR;
            app.editedLocal = cat(3, redChannel, greenChannel, blueChannel);
            imshow(app.editedLocal,'Parent', app.UIAxes);
        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            app.edited = app.editedLocal;
            app.Switch.Value = 'Edited';
            app.BLamp.Color = 'green';
            app.BlueSlider.Value = 2.5;
            if app.histo == 1
                imhist(app.edited)
            end
            
        end

        % Value changing function: BlueSlider
        function BlueSliderValueChanging(app, event)
            changingValue = event.Value;    
            rgbImage = app.edited;
            redChannel = rgbImage(:, :, 1);
            greenChannel = rgbImage(:, :, 2);
            blueChannel = rgbImage(:, :, 3);
            slopeR = changingValue;
            interceptR = changingValue;
            blueChannel = slopeR * blueChannel + interceptR;
            app.editedLocal = cat(3, redChannel, greenChannel, blueChannel);
            imshow(app.editedLocal,'Parent', app.UIAxes);
            
        end

        % Button pushed function: GrayscaleButton
        function GrayscaleButtonPushed(app, event)
            app.editedLocal = rgb2gray(app.edited);
            app.GrayscaleButton.BackgroundColor = [0.902 0.902 0.902];
            app.GrayscaleButton.FontColor = [0 0 0];
            app.RedButton.BackgroundColor = [0.651 0.651 0.651];
            app.RedButton.FontColor = [1 0.1412 0.1412];
            app.GreenButton.BackgroundColor = [0.651 0.651 0.651];
            app.GreenButton.FontColor = [0.1765 0.6588 0.0118];
            app.BlueButton.BackgroundColor = [0.651 0.651 0.651];
            app.BlueButton.FontColor = [0 0.4471 0.7412];
            app.BinaryButton.BackgroundColor = [0.651 0.651 0.651];
            app.BinaryButton.FontColor = [1 1 1];
            imshow(app.editedLocal,'Parent', app.UIAxes);
            if app.histo == 1
                imhist(app.editedLocal)
            end
        end

        % Button pushed function: BinaryButton
        function BinaryButtonPushed(app, event)
            app.editedLocal = im2bw(app.edited);
            app.BinaryButton.BackgroundColor = [0 0 0];
            app.RedButton.BackgroundColor = [0.651 0.651 0.651];
            app.RedButton.FontColor = [1 0.1412 0.1412];
            app.GreenButton.BackgroundColor = [0.651 0.651 0.651];
            app.GreenButton.FontColor = [0.1765 0.6588 0.0118];
            app.BlueButton.BackgroundColor = [0.651 0.651 0.651];
            app.BlueButton.FontColor = [0 0.4471 0.7412];
            app.GrayscaleButton.BackgroundColor = [0.651 0.651 0.651];
            app.GrayscaleButton.FontColor = [0.9412 0.9412 0.9412];
            imshow(app.editedLocal,'Parent', app.UIAxes);
            if app.histo == 1
                imhist(app.editedLocal)
            end
        end

        % Value changing function: RotateSlider
        function RotateSliderValueChanging(app, event)
            changingValue = event.Value;
            app.editedLocal = imrotate(app.edited,changingValue,'bilinear','crop');
            imshow(app.editedLocal,'Parent', app.UIAxes);
        end

        % Value changed function: RotateSlider
        function RotateSliderValueChanged(app, event)
            app.edited = app.editedLocal;
            app.Switch.Value = 'Edited';
            app.RoLamp.Color = 'green';
            app.RotateSlider.Value = 0;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageUIFigure = uifigure('Visible', 'off');
            app.ImageUIFigure.Name = 'Image';
            app.UIAxes = uiaxes(app.ImageUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.Panel = uipanel(app.ImageUIFigure);
            app.BrightnessSliderLabel = uilabel(app.Panel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.Panel);
            app.BrightnessSlider.Limits = [-100 100];
            app.BrightnessSlider.MajorTicks = [];
            app.BrightnessSlider.MajorTickLabels = {''};
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessSliderValueChanging, true);
            app.BrightnessSlider.MinorTicks = [];
            app.ContrastSliderLabel = uilabel(app.Panel);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.Panel);
            app.ContrastSlider.Limits = [-1 1];
            app.ContrastSlider.MajorTicks = [];
            app.ContrastSlider.MajorTickLabels = {''};
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.ValueChangingFcn = createCallbackFcn(app, @ContrastSliderValueChanging, true);
            app.ContrastSlider.MinorTicks = [];
            app.BrLamp = uilamp(app.Panel);
            app.CntLamp = uilamp(app.Panel);
            app.RLamp = uilamp(app.Panel);
            app.RedSliderLabel = uilabel(app.Panel);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.Panel);
            app.RedSlider.Limits = [0 5];
            app.RedSlider.MajorTicks = [];
            app.RedSlider.MajorTickLabels = {''};
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.RedSlider.ValueChangingFcn = createCallbackFcn(app, @RedSliderValueChanging, true);
            app.RedSlider.MinorTicks = [];
            app.RedSlider.Value = 2.5;
            app.GLamp = uilamp(app.Panel);
            app.GreenSliderLabel = uilabel(app.Panel);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.Panel);
            app.GreenSlider.Limits = [0 5];
            app.GreenSlider.MajorTicks = [];
            app.GreenSlider.MajorTickLabels = {''};
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.GreenSlider.ValueChangingFcn = createCallbackFcn(app, @GreenSliderValueChanging, true);
            app.GreenSlider.MinorTicks = [];
            app.GreenSlider.Value = 2.5;
            app.BLamp = uilamp(app.Panel);
            app.BlueSliderLabel = uilabel(app.Panel);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.Panel);
            app.BlueSlider.Limits = [0 5];
            app.BlueSlider.MajorTicks = [];
            app.BlueSlider.MajorTickLabels = {''};
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            app.BlueSlider.ValueChangingFcn = createCallbackFcn(app, @BlueSliderValueChanging, true);
            app.BlueSlider.MinorTicks = [];
            app.BlueSlider.Value = 2.5;
            app.RedButton = uibutton(app.Panel, 'push');
            app.RedButton.ButtonPushedFcn = createCallbackFcn(app, @RedButtonPushed, true);
            app.RedButton.Text = 'Red';
            app.GreenButton = uibutton(app.Panel, 'push');
            app.GreenButton.ButtonPushedFcn = createCallbackFcn(app, @GreenButtonPushed, true);
            app.GreenButton.Text = 'Green';
            app.BlueButton = uibutton(app.Panel, 'push');
            app.BlueButton.ButtonPushedFcn = createCallbackFcn(app, @BlueButtonPushed, true);
            app.BlueButton.Text = 'Blue';
            app.GrayscaleButton = uibutton(app.Panel, 'push');
            app.GrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @GrayscaleButtonPushed, true);
            app.GrayscaleButton.Text = 'Grayscale';
            app.BinaryButton = uibutton(app.Panel, 'push');
            app.BinaryButton.ButtonPushedFcn = createCallbackFcn(app, @BinaryButtonPushed, true);
            app.BinaryButton.Text = 'Binary';
            app.RoLamp = uilamp(app.Panel);
            app.RotateSliderLabel = uilabel(app.Panel);
            app.RotateSliderLabel.Text = 'Rotate';
            app.RotateSlider = uislider(app.Panel);
            app.RotateSlider.Limits = [-180 180];
            app.RotateSlider.MajorTicks = [];
            app.RotateSlider.MajorTickLabels = {''};
            app.RotateSlider.ValueChangedFcn = createCallbackFcn(app, @RotateSliderValueChanged, true);
            app.RotateSlider.ValueChangingFcn = createCallbackFcn(app, @RotateSliderValueChanging, true);
            app.RotateSlider.MinorTicks = [];
            app.Label = uilabel(app.ImageUIFigure);
            app.Label.Text = '';
            app.Switch = uiswitch(app.ImageUIFigure, 'slider');
            app.Switch.Items = {'Original', 'Edited'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Original';
            app.ResetButton = uibutton(app.ImageUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = 'round_loop_white_18dp.png';
            app.ResetButton.Text = '';
            app.iButton = uibutton(app.ImageUIFigure, 'push');
            app.iButton.ButtonPushedFcn = createCallbackFcn(app, @iButtonPushed, true);
            app.iButton.Text = 'i';
            app.homeButton = uibutton(app.ImageUIFigure, 'push');
            app.homeButton.ButtonPushedFcn = createCallbackFcn(app, @homeButtonPushed, true);
            app.homeButton.Icon = 'round_home_white_18dp.png';
            app.homeButton.Text = '';
            app.histButton = uibutton(app.ImageUIFigure, 'push');
            app.histButton.ButtonPushedFcn = createCallbackFcn(app, @histButtonPushed, true);
            app.histButton.Icon = 'round_bar_chart_white_18dp.png';
            app.histButton.Text = '';
            % Show the figure after all components are created
            app.ImageUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageUIFigure)
        end
    end
end